classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        ReadNLELButton             matlab.ui.control.Button
        ButtonGroup                matlab.ui.container.ButtonGroup
        Bone                       matlab.ui.control.ToggleButton
        Parula                     matlab.ui.control.ToggleButton
        Jet                        matlab.ui.control.ToggleButton
        NodeLabelSwitch            matlab.ui.control.Switch
        NodeLabelSwitchLabel       matlab.ui.control.Label
        ScaleSlider                matlab.ui.control.Slider
        ScaleSliderLabel           matlab.ui.control.Label
        ElementListLabel           matlab.ui.control.Label
        EL_Table                   matlab.ui.control.Table
        GenerateElementButton      matlab.ui.control.Button
        ElementLabel               matlab.ui.control.Label
        Node2                      matlab.ui.control.NumericEditField
        Node2EditFieldLabel        matlab.ui.control.Label
        Node1                      matlab.ui.control.NumericEditField
        Node1EditFieldLabel        matlab.ui.control.Label
        NodeListLabel              matlab.ui.control.Label
        NL_Table                   matlab.ui.control.Table
        GenerateNodeButton         matlab.ui.control.Button
        NodeLabel                  matlab.ui.control.Label
        Y_coord                    matlab.ui.control.NumericEditField
        YCoordinateEditFieldLabel  matlab.ui.control.Label
        X_coord                    matlab.ui.control.NumericEditField
        XCoordinateEditFieldLabel  matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        NL
        EL
        NoN
        NoE
        scale = 10
        NodeSwitch = "On"
        colorMap = jet
    end
    
    methods (Access = public)
        
        function results = updateAxes(app)

            cla(app.UIAxes);
            
            colormap(app.UIAxes, app.colorMap);
            colorbar(app.UIAxes);

            hold(app.UIAxes,"on");

           for i = 1 : app.NoE
                H = plot(app.UIAxes,[app.NL(app.EL(i,1),1), app.NL(app.EL(i,2),1)], [app.NL(app.EL(i,1),2),app.NL(app.EL(i,2),2)], "LineWidth", 3, "Color", "w");
                H.Color(4) = 0.2; % alpha = 0.2

           end

           for i = 1 : app.NoN
                plot(app.UIAxes,app.NL(i,1), app.NL(i,2), 'o', 'MarkerSize',app.scale, 'MarkerFaceColor', 'r')
           end
            
           switch app.NodeSwitch

               case "On"

                    for i = 1:app.NoN

                    text(app.UIAxes,app.NL(i,1),app.NL(i,2),num2str(i),"Color","w","FontSize",12,"HorizontalAlignment","center")
                
                    end
           end


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateNodeButton
        function GenerateNodeButtonPushed(app, event)
            nl = [app.X_coord.Value, app.Y_coord.Value];
 
            app.NL = [app.NL; nl];

            app.NL_Table.Data = app.NL;
            
            app.NoN = size(app.NL, 1);
            
            updateAxes(app);

        end

        % Button pushed function: GenerateElementButton
        function GenerateElementButtonPushed(app, event)
           el = [app.Node1.Value, app.Node2.Value];

           if app.NoN < 2

               uialert(app.UIFigure,"At least 2 nodes are needed to create an element! You have: " + app.NoN,"Input Error")

           else
    
               app.EL = [app.EL; el];
    
               app.EL_Table.Data = app.EL;
    
               app.NoE = size(app.EL,1);
    
               updateAxes(app);
           
           end
        end

        % Callback function
        function ScaleSliderValueChanged(app, event)


        end

        % Value changed function: ScaleSlider
        function ScaleSliderValueChanged2(app, event)
            app.scale = app.ScaleSlider.Value;
            
            updateAxes(app);
            
        end

        % Value changed function: NodeLabelSwitch
        function NodeLabelSwitchValueChanged(app, event)
            app.NodeSwitch = app.NodeLabelSwitch.Value;
            
            updateAxes(app);

        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            SelectedButton = app.ButtonGroup.SelectedObject;
            
            switch SelectedButton

                case app.Jet

                    app.colorMap = jet;
                    colormap(app.UIAxes, app.colorMap);

                case app.Parula

                    app.colorMap = parula;
                    colormap(app.UIAxes, app.colorMap);

                case app.Bone

                    app.colorMap = bone;
                    colormap(app.UIAxes, app.colorMap);

                    
            end
        end

        % Button pushed function: ReadNLELButton
        function ReadNLELButtonPushed(app, event)
            [inputfile, pathname] = uigetfile({"*.xlsx";"*.slx"}, "Select file to open");
    
            if ~isequal(inputfile, 0)

                fullPath = fullfile(pathname, inputfile);
                
  
                nl_raw = readmatrix(fullPath, "Range", "A:B");
                el_raw = readmatrix(fullPath, "Range", "C:D");
                

                app.NL = nl_raw(~any(isnan(nl_raw), 2), :);
                app.EL = el_raw(~any(isnan(el_raw), 2), :);
                

                app.NL_Table.Data = app.NL;
                app.EL_Table.Data = app.EL;
                

                app.NoN = size(app.NL, 1);
                app.NoE = size(app.EL, 1);
                

                updateAxes(app);
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.XCoordinateEditFieldLabel = uilabel(app.GridLayout);
            app.XCoordinateEditFieldLabel.Text = 'X Coordinate';
            app.X_coord = uieditfield(app.GridLayout, 'numeric');
            app.YCoordinateEditFieldLabel = uilabel(app.GridLayout);
            app.YCoordinateEditFieldLabel.Text = 'Y Coordinate';
            app.Y_coord = uieditfield(app.GridLayout, 'numeric');
            app.NodeLabel = uilabel(app.GridLayout);
            app.NodeLabel.Text = 'Node';
            app.GenerateNodeButton = uibutton(app.GridLayout, 'push');
            app.GenerateNodeButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateNodeButtonPushed, true);
            app.GenerateNodeButton.Text = {'Generate'; 'Node'};
            app.NL_Table = uitable(app.GridLayout);
            app.NL_Table.ColumnName = {'X'; 'Y'};
            app.NL_Table.RowName = {};
            app.NodeListLabel = uilabel(app.GridLayout);
            app.NodeListLabel.Text = 'Node List';
            app.Node1EditFieldLabel = uilabel(app.GridLayout);
            app.Node1EditFieldLabel.Text = 'Node 1';
            app.Node1 = uieditfield(app.GridLayout, 'numeric');
            app.Node2EditFieldLabel = uilabel(app.GridLayout);
            app.Node2EditFieldLabel.Text = 'Node 2';
            app.Node2 = uieditfield(app.GridLayout, 'numeric');
            app.ElementLabel = uilabel(app.GridLayout);
            app.ElementLabel.Text = 'Element';
            app.GenerateElementButton = uibutton(app.GridLayout, 'push');
            app.GenerateElementButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateElementButtonPushed, true);
            app.GenerateElementButton.Text = {'Generate'; 'Element'};
            app.EL_Table = uitable(app.GridLayout);
            app.EL_Table.ColumnName = {'Node 1'; 'Node 2'};
            app.EL_Table.RowName = {};
            app.ElementListLabel = uilabel(app.GridLayout);
            app.ElementListLabel.Text = 'Element List';
            app.ScaleSliderLabel = uilabel(app.GridLayout);
            app.ScaleSliderLabel.Text = 'Scale';
            app.ScaleSlider = uislider(app.GridLayout);
            app.ScaleSlider.Limits = [1 100];
            app.ScaleSlider.ValueChangedFcn = createCallbackFcn(app, @ScaleSliderValueChanged2, true);
            app.ScaleSlider.Value = 10;
            app.NodeLabelSwitchLabel = uilabel(app.GridLayout);
            app.NodeLabelSwitchLabel.Text = 'Node Label';
            app.NodeLabelSwitch = uiswitch(app.GridLayout, 'slider');
            app.NodeLabelSwitch.ValueChangedFcn = createCallbackFcn(app, @NodeLabelSwitchValueChanged, true);
            app.NodeLabelSwitch.Value = 'On';
            app.ButtonGroup = uibuttongroup(app.GridLayout);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.Jet = uitogglebutton(app.ButtonGroup);
            app.Jet.Text = 'Jet';
            app.Jet.Value = true;
            app.Parula = uitogglebutton(app.ButtonGroup);
            app.Parula.Text = 'Parula';
            app.Bone = uitogglebutton(app.ButtonGroup);
            app.Bone.Text = 'Bone';
            app.ReadNLELButton = uibutton(app.GridLayout, 'push');
            app.ReadNLELButton.ButtonPushedFcn = createCallbackFcn(app, @ReadNLELButtonPushed, true);
            app.ReadNLELButton.Text = 'Read NL & EL';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end